classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NPMFOPDTUNERUIFigure           matlab.ui.Figure
        NPMFOPDTunerLabel              matlab.ui.control.Label
        TuneIOPIDcontrollerButton      matlab.ui.control.Button
        NeighborhoodphasemargintunerforFOPDcontrollerLabel  matlab.ui.control.Label
        TuneFOPDcontrollerButton       matlab.ui.control.Button
        StatusLabel                    matlab.ui.control.Label
        NotrunningLabel                matlab.ui.control.Label
        PlantgainkEditFieldLabel       matlab.ui.control.Label
        PlantgainkEditField            matlab.ui.control.NumericEditField
        PlanttimeconstanttauEditFieldLabel  matlab.ui.control.Label
        PlanttimeconstanttauEditField  matlab.ui.control.NumericEditField
        Systemcrossoverfrequencyw_cEditFieldLabel  matlab.ui.control.Label
        Systemcrossoverfrequencyw_cEditField  matlab.ui.control.NumericEditField
        SystemphasemarginphiEditFieldLabel  matlab.ui.control.Label
        SystemphasemarginphiEditField  matlab.ui.control.NumericEditField
        k_pEditFieldLabel              matlab.ui.control.Label
        k_pEditField                   matlab.ui.control.NumericEditField
        k_iEditFieldLabel              matlab.ui.control.Label
        k_iEditField                   matlab.ui.control.NumericEditField
        k_dEditFieldLabel              matlab.ui.control.Label
        k_dEditField                   matlab.ui.control.NumericEditField
        k_pEditField_2Label            matlab.ui.control.Label
        k_pEditField_fo                matlab.ui.control.NumericEditField
        k_dEditField_3Label            matlab.ui.control.Label
        k_dEditField_fo                matlab.ui.control.NumericEditField
        lambdaEditFieldLabel           matlab.ui.control.Label
        lambdaEditField                matlab.ui.control.NumericEditField
        StepresponsesinlinearmodelButton  matlab.ui.control.Button
        LoadSimscapeplantButton        matlab.ui.control.Button
        TuneIOPDcontrollerButton       matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: TuneIOPIDcontrollerButton
        function TuneIOPIDcontrollerButtonPushed(app, event)
            app.NotrunningLabel.Text='Running.';
            global K T1 wc phi %inputs
            K = app.PlantgainkEditField.Value; %plant gain
            T1 = app.PlanttimeconstanttauEditField.Value; %plant time constant
            wc = app.Systemcrossoverfrequencyw_cEditField.Value; %crossover frequency
            phi = app.SystemphasemarginphiEditField.Value; %phase margin
            pause on % to enable pause function
            pause(0.1);
            iopid_tune;
            %run_patternsearch_npm;
            %app.k_pEditField.Value=800;
            global iokp ioki iokd
            
            app.k_pEditField.Value=iokp;
            app.k_iEditField.Value=ioki;
            app.k_dEditField.Value=iokd;
            app.NotrunningLabel.Text='Ready.';   
        end

        % Button pushed function: TuneFOPDcontrollerButton
        function TuneFOPDcontrollerButtonPushed(app, event)
            app.NotrunningLabel.Text='Running.';
            
            pause on
            pause(0.1);
            global K T1
            K = app.PlantgainkEditField.Value; %plant gain
            T1 = app.PlanttimeconstanttauEditField.Value; %plant time constant
            global omega_c phi_m
            omega_c=app.Systemcrossoverfrequencyw_cEditField.Value;
            phi_m=app.SystemphasemarginphiEditField.Value;
            global ioKp ioKi ioKd
            ioKp=app.k_pEditField.Value;
            ioKi=app.k_iEditField.Value;
            ioKd=app.k_dEditField.Value;
            run_patternsearch_apm;
            global lamda kd kp
            app.k_pEditField_fo.Value=kp;
%             app.k_iEditField_fo.Value=x(2);
            app.k_dEditField_fo.Value=kd;
            app.lambdaEditField.Value=lamda;
%             app.muEditField.Value=x(5);
            app.NotrunningLabel.Text='Ready.';
        end

        % Button pushed function: StepresponsesinlinearmodelButton
        function StepresponsesinlinearmodelButtonPushed(app, event)
            app.NotrunningLabel.Text='Running.';
            
            pause on
            pause(0.1);
            run_simulink_kd;
            app.NotrunningLabel.Text='Ready.';
        end

        % Button pushed function: LoadSimscapeplantButton
        function LoadSimscapeplantButtonPushed(app, event)
            app.PlantgainkEditField.Value = 1.102; %plant gain (k)
            app.PlanttimeconstanttauEditField.Value = 0.179; %(T1) plant time constant

        end

        % Button pushed function: TuneIOPDcontrollerButton
        function TuneIOPDcontrollerButtonPushed(app, event)
            app.NotrunningLabel.Text='Running.';
            global K T1 wc phi %inputs
            K = app.PlantgainkEditField.Value; %plant gain
            T1 = app.PlanttimeconstanttauEditField.Value; %plant time constant
            wc = app.Systemcrossoverfrequencyw_cEditField.Value; %crossover frequency
            phi = app.SystemphasemarginphiEditField.Value; %phase margin
            pause on % to enable pause function
            pause(0.1);
            iopd_tune;
            %run_patternsearch_npm;
            %app.k_pEditField.Value=800;
            global iokp ioki iokd
            
            app.k_pEditField.Value=iokp;
            app.k_iEditField.Value=ioki;
            app.k_dEditField.Value=iokd;
            app.NotrunningLabel.Text='Ready.';    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NPMFOPDTUNERUIFigure = uifigure;
            app.NPMFOPDTUNERUIFigure.Name = 'NPM FOPD TUNER';
            app.NPMFOPDTunerLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.NPMFOPDTunerLabel.Text = 'NPM FOPD Tuner';
            app.TuneIOPIDcontrollerButton = uibutton(app.NPMFOPDTUNERUIFigure, 'push');
            app.TuneIOPIDcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @TuneIOPIDcontrollerButtonPushed, true);
            app.TuneIOPIDcontrollerButton.Text = 'Tune IOPID controller';
            app.NeighborhoodphasemargintunerforFOPDcontrollerLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.NeighborhoodphasemargintunerforFOPDcontrollerLabel.Text = 'Neighborhood phase margin tuner for FOPD controller';
            app.TuneFOPDcontrollerButton = uibutton(app.NPMFOPDTUNERUIFigure, 'push');
            app.TuneFOPDcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @TuneFOPDcontrollerButtonPushed, true);
            app.TuneFOPDcontrollerButton.Text = 'Tune FOPD controller';
            app.StatusLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.StatusLabel.Text = 'Status:';
            app.NotrunningLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.NotrunningLabel.Text = 'Not running.';
            app.PlantgainkEditFieldLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.PlantgainkEditFieldLabel.Text = 'Plant gain (k)';
            app.PlantgainkEditField = uieditfield(app.NPMFOPDTUNERUIFigure, 'numeric');
            app.PlantgainkEditField.ValueDisplayFormat = '%11.5g';
            app.PlantgainkEditField.Value = 1.0263;
            app.PlanttimeconstanttauEditFieldLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.PlanttimeconstanttauEditFieldLabel.Text = 'Plant time constant (tau)';
            app.PlanttimeconstanttauEditField = uieditfield(app.NPMFOPDTUNERUIFigure, 'numeric');
            app.PlanttimeconstanttauEditField.Value = 0.71;
            app.Systemcrossoverfrequencyw_cEditFieldLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.Systemcrossoverfrequencyw_cEditFieldLabel.Text = 'System crossover frequency (w_c)';
            app.Systemcrossoverfrequencyw_cEditField = uieditfield(app.NPMFOPDTUNERUIFigure, 'numeric');
            app.Systemcrossoverfrequencyw_cEditField.Value = 2.51;
            app.SystemphasemarginphiEditFieldLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.SystemphasemarginphiEditFieldLabel.Text = 'System phase margin (phi)';
            app.SystemphasemarginphiEditField = uieditfield(app.NPMFOPDTUNERUIFigure, 'numeric');
            app.SystemphasemarginphiEditField.Value = 83.9;
            app.k_pEditFieldLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.k_pEditFieldLabel.Text = 'k_p';
            app.k_pEditField = uieditfield(app.NPMFOPDTUNERUIFigure, 'numeric');
            app.k_iEditFieldLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.k_iEditFieldLabel.Text = 'k_i';
            app.k_iEditField = uieditfield(app.NPMFOPDTUNERUIFigure, 'numeric');
            app.k_dEditFieldLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.k_dEditFieldLabel.Text = 'k_d';
            app.k_dEditField = uieditfield(app.NPMFOPDTUNERUIFigure, 'numeric');
            app.k_pEditField_2Label = uilabel(app.NPMFOPDTUNERUIFigure);
            app.k_pEditField_2Label.Text = 'k_p';
            app.k_pEditField_fo = uieditfield(app.NPMFOPDTUNERUIFigure, 'numeric');
            app.k_dEditField_3Label = uilabel(app.NPMFOPDTUNERUIFigure);
            app.k_dEditField_3Label.Text = 'k_d';
            app.k_dEditField_fo = uieditfield(app.NPMFOPDTUNERUIFigure, 'numeric');
            app.lambdaEditFieldLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.NPMFOPDTUNERUIFigure, 'numeric');
            app.StepresponsesinlinearmodelButton = uibutton(app.NPMFOPDTUNERUIFigure, 'push');
            app.StepresponsesinlinearmodelButton.ButtonPushedFcn = createCallbackFcn(app, @StepresponsesinlinearmodelButtonPushed, true);
            app.StepresponsesinlinearmodelButton.Text = 'Step responses in linear model';
            app.LoadSimscapeplantButton = uibutton(app.NPMFOPDTUNERUIFigure, 'push');
            app.LoadSimscapeplantButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSimscapeplantButtonPushed, true);
            app.LoadSimscapeplantButton.Text = 'Load Simscape plant';
            app.TuneIOPDcontrollerButton = uibutton(app.NPMFOPDTUNERUIFigure, 'push');
            app.TuneIOPDcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @TuneIOPDcontrollerButtonPushed, true);
            app.TuneIOPDcontrollerButton.Text = 'Tune IOPD controller';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NPMFOPDTUNERUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NPMFOPDTUNERUIFigure)
        end
    end
end